classdef videopoker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        WinningHand  matlab.ui.control.Label
        C5Held       matlab.ui.control.Label
        C4Held       matlab.ui.control.Label
        C3Held       matlab.ui.control.Label
        C2Held       matlab.ui.control.Label
        C1Held       matlab.ui.control.Label
        Replace      matlab.ui.control.Button
        Deal         matlab.ui.control.Button
        Card5        matlab.ui.control.Image
        Card4        matlab.ui.control.Image
        Card3        matlab.ui.control.Image
        Card2        matlab.ui.control.Image
        Card1        matlab.ui.control.Image
        Background   matlab.ui.control.Image
    end


    properties (Access = private)
        cards = {"C2.png", 2, 'c'; "C3.png", 3, 'c'; ...
            "C4.png", 4, 'c'; "C5.png", 5, 'c'; "C6.png", 6, 'c'; ...
            "C7.png", 7, 'c'; "C8.png", 8, 'c'; "C9.png", 9, 'c'; ...
            "C10.png", 10, 'c'; "CJ.png", 11, 'c'; "CQ.png", 12, 'c'; ...
            "CK.png", 13, 'c'; "CA.png", 14, 'c'; ...
            "D2.png", 2, 'd'; "D3.png", 3, 'd'; ...
            "D4.png", 4, 'd'; "D5.png", 5, 'd'; "D6.png", 6, 'd'; ...
            "D7.png", 7, 'd'; "D8.png", 8, 'd'; "D9.png", 9, 'd'; ...
            "D10.png", 10, 'd'; "DJ.png", 11, 'd'; "DQ.png", 12, 'd'; ...
            "DK.png", 13, 'd'; "DA.png", 14, 'd'; ...
            "H2.png", 2, 'h'; "H3.png", 3, 'h'; ...
            "H4.png", 4, 'h'; "H5.png", 5, 'h'; "H6.png", 6, 'h'; ...
            "H7.png", 7, 'h'; "H8.png", 8, 'h'; "H9.png", 9, 'h'; ...
            "H10.png", 10, 'h'; "HJ.png", 11, 'h'; "HQ.png", 12, 'h'; ...
            "HK.png", 13, 'h'; "HA.png", 14, 'h'; ...
            "S2.png", 2, 's'; "S3.png", 3, 's'; ...
            "S4.png", 4, 's'; "S5.png", 5, 's'; "S6.png", 6, 's'; ...
            "S7.png", 7, 's'; "S8.png", 8, 's'; "S9.png", 9, 's'; ...
            "S10.png", 10, 's'; "SJ.png", 11, 's'; "SQ.png", 12, 's'; ...
            "SK.png", 13, 's'; "SA.png", 14, 's'};

        shufCards = zeros(1,52);

        n = 0;

        cardArray; % Initializing so I can make thing cleaner, see startupFcn()


        handCards = [0 0 0 0 0] % tracks index of card type for all held cards
        cardLock = [0 0 0 0 0] % marks a card locked
        holdAbility = 0;
    end

    methods (Access = private)
        % just adding this function to make it easier to update all the
        % held labels since I need to reuse it
        function updateHeld(app)
            app.C1Held.Visible = app.cardLock(1);
            app.C2Held.Visible = app.cardLock(2);
            app.C3Held.Visible = app.cardLock(3);
            app.C4Held.Visible = app.cardLock(4);
            app.C5Held.Visible = app.cardLock(5);
        end

        function SetupDeal(app)
            app.Deal.Enable = 0;

            app.WinningHand.Text = '';
            app.cardLock = [0 0 0 0 0];

            app.updateHeld(); % this just makes the code a little cleaner, not really critical

            % could also shorten this with a for loop, but why bother :P
            app.Card1.ImageSource="CardBackFixed.png";
            app.Card2.ImageSource="CardBackFixed.png";
            app.Card3.ImageSource="CardBackFixed.png";
            app.Card4.ImageSource="CardBackFixed.png";
            app.Card5.ImageSource="CardBackFixed.png";

            % creating the random array of card indeces
            app.shufCards = randperm(52);
            app.n=0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Have to assign values of this array after initialization since Card objects don't technically load yet
            app.cardArray = [app.Card1, app.Card2, app.Card3, app.Card4, app.Card5];
        end

        % Button pushed function: Deal
        function DealFunc(app, event)
            app.SetupDeal();

            % this is just a cleaner way of manually copy pasting this line
            % 5 times. It iterates through all five cards (stored inn the
            % cardArray vector)
            for cardPlace = 1:5
                app.n = app.n + 1;
                app.handCards(cardPlace) = app.shufCards(app.n);
                app.cardArray(cardPlace).ImageSource = app.cards{app.shufCards(app.n), 1};
                pause(0.25);
            end

            app.Replace.Enable = 1;
            app.holdAbility = 1;

        end

        % Button pushed function: Replace
        function ReplaceFunc(app, event)
            app.Replace.Enable = 0;
            app.holdAbility = 0;

            for cardPlace = 1:5
                if app.cardLock(cardPlace) == 0
                    app.n=app.n + 1;
                    app.handCards(cardPlace) = app.shufCards(app.n);
                    app.cardArray(cardPlace).ImageSource = app.cards{app.shufCards(app.n), 1};
                    pause(0.25);
                end
            end

            app.Deal.Enable = 1;
            app.holdAbility = 1;

            values = [app.cards{app.handCards,2}];
            suits = [app.cards{app.handCards,3}];

            JQKAFilter = [0 0 0 0 0 0 0 0 0 0 1 1 1 1]; % (I will use this
            % to filter out other non-face cards by value. There
            % probably was a cleaner way to do it though haha). In
            % hindsight, I only used this for jacks or better check haha

            % after not liking how histcounts sets the edges, I looked in
            % the docs and found that you can specify them. Turns out, you
            % can specify a with colon operator. 1:15 as the edges works to align the
            % values and index of histcount's output
            valueBins = histcounts(values,1:15);

            if (all(sort(values) == [10 11 12 13 14])) && all(suits == suits(1))
                app.WinningHand.Text = 'Royal Flush';
                % refer to my Straight check for more details (this one just
                % has to also fulfill the requirements that a Flush does)
            elseif (all(diff(sort(values)) == 1) || all(sort(values) == [2 3 4 5 14])) && all(suits == suits(1))
                app.WinningHand.Text = 'Straight Flush';
                
            elseif any(valueBins >= 4)
                app.WinningHand.Text = 'Four of a Kind';

            elseif any(valueBins == 3) && any(valueBins == 2)
                app.WinningHand.Text = 'Full House';

                % could use any index to compare in the array, but 1 seems cleanest
            elseif all(suits == suits(1))
                app.WinningHand.Text = 'Flush';

                % For this one, it was interesting. If all cards are
                % consecutive (meaning their value difference is all one) OR
                % if the sorted hand exactly matched the ace case, it's a straight
            elseif all(diff(sort(values)) == 1) || all(sort(values) == [2 3 4 5 14])
                app.WinningHand.Text = 'Straight';

            elseif any(valueBins >= 3)
                app.WinningHand.Text = 'Three of a Kind';

            elseif sum(valueBins >= 2) == 2
                app.WinningHand.Text = 'Two Pair';

            elseif any(valueBins >= 2 & JQKAFilter)
                app.WinningHand.Text = 'Jacks or Better';
            end

        end

        % Image clicked function: Card1
        function holdCard1(app, event)
            % My logic may seem a bit confusing using xor. I could have
            % used an if statement, but I felt this was more optimized.

            % Basically, if the current state of cardLock is 0, it can only
            % be inverted if holdAbility is 1 (result of xor(0,1) is 1,
            % which that element of cardLock becomes set to).
            app.cardLock(1) = xor(app.cardLock(1),app.holdAbility);
            app.C1Held.Visible = app.cardLock(1);
        end

        % Image clicked function: Card2
        function holdCard2(app, event)
            app.cardLock(2) = xor(app.cardLock(2),app.holdAbility);
            app.C2Held.Visible = app.cardLock(2);
        end

        % Image clicked function: Card3
        function holdCard3(app, event)
            app.cardLock(3) = xor(app.cardLock(3),app.holdAbility);
            app.C3Held.Visible = app.cardLock(3);
        end

        % Image clicked function: Card4
        function holdCard4(app, event)
            app.cardLock(4) = xor(app.cardLock(4),app.holdAbility);
            app.C4Held.Visible = app.cardLock(4);
        end

        % Image clicked function: Card5
        function holdCard5(app, event)
            app.cardLock(5) = xor(app.cardLock(5),app.holdAbility);
            app.C5Held.Visible = app.cardLock(5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'greenFelt.jpg');
            app.Card1 = uiimage(app.UIFigure);
            app.Card1.ImageClickedFcn = createCallbackFcn(app, @holdCard1, true);
            app.Card1.ImageSource = fullfile(pathToMLAPP, 'CardBackFixed.png');
            app.Card2 = uiimage(app.UIFigure);
            app.Card2.ImageClickedFcn = createCallbackFcn(app, @holdCard2, true);
            app.Card2.ImageSource = fullfile(pathToMLAPP, 'CardBackFixed.png');
            app.Card3 = uiimage(app.UIFigure);
            app.Card3.ImageClickedFcn = createCallbackFcn(app, @holdCard3, true);
            app.Card3.ImageSource = fullfile(pathToMLAPP, 'CardBackFixed.png');
            app.Card4 = uiimage(app.UIFigure);
            app.Card4.ImageClickedFcn = createCallbackFcn(app, @holdCard4, true);
            app.Card4.ImageSource = fullfile(pathToMLAPP, 'CardBackFixed.png');
            app.Card5 = uiimage(app.UIFigure);
            app.Card5.ImageClickedFcn = createCallbackFcn(app, @holdCard5, true);
            app.Card5.ImageSource = fullfile(pathToMLAPP, 'CardBackFixed.png');
            app.Deal = uibutton(app.UIFigure, 'push');
            app.Deal.ButtonPushedFcn = createCallbackFcn(app, @DealFunc, true);
            app.Deal.Text = 'Deal';
            app.Replace = uibutton(app.UIFigure, 'push');
            app.Replace.ButtonPushedFcn = createCallbackFcn(app, @ReplaceFunc, true);
            app.Replace.Enable = 'off';
            app.Replace.Text = 'Replace';
            app.C1Held = uilabel(app.UIFigure);
            app.C1Held.Visible = 'off';
            app.C1Held.Text = 'HELD';
            app.C2Held = uilabel(app.UIFigure);
            app.C2Held.Visible = 'off';
            app.C2Held.Text = 'HELD';
            app.C3Held = uilabel(app.UIFigure);
            app.C3Held.Visible = 'off';
            app.C3Held.Text = 'HELD';
            app.C4Held = uilabel(app.UIFigure);
            app.C4Held.Visible = 'off';
            app.C4Held.Text = 'HELD';
            app.C5Held = uilabel(app.UIFigure);
            app.C5Held.Visible = 'off';
            app.C5Held.Text = 'HELD';
            app.WinningHand = uilabel(app.UIFigure);
            app.WinningHand.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = videopoker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end